classdef DO_AN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        NghiemTab                      matlab.ui.container.Tab
        NghiemButton                   matlab.ui.control.Button
        SoLanLap                       matlab.ui.control.NumericEditField
        SlnlpEditFieldLabel            matlab.ui.control.Label
        KetQua_1                       matlab.ui.control.NumericEditField
        KtquEditFieldLabel             matlab.ui.control.Label
        SaiSo_1                        matlab.ui.control.NumericEditField
        SaischophpEditFieldLabel       matlab.ui.control.Label
        PhuongPhapTinh_1               matlab.ui.control.DropDown
        PhngphptnhDropDownLabel        matlab.ui.control.Label
        a_1                            matlab.ui.control.NumericEditField
        KhongphnlinghimEditFieldLabel  matlab.ui.control.Label
        NhapPhuongTrinh_1              matlab.ui.control.EditField
        NhpphngtrnhEditFieldLabel      matlab.ui.control.Label
        Lamp_1                         matlab.ui.control.Lamp
        SymLabel_1_2                   matlab.ui.control.Label
        SymLabel_1_3                   matlab.ui.control.Label
        SymLabel_1_1                   matlab.ui.control.Label
        b_1                            matlab.ui.control.NumericEditField
        UIAxes_1                       matlab.ui.control.UIAxes
        NoiSuyTab                      matlab.ui.container.Tab
        NoiSuyButton                   matlab.ui.control.Button
        DaThucNoiSuy                   matlab.ui.control.EditField
        athcnisuyLabel                 matlab.ui.control.Label
        KetQua_2                       matlab.ui.control.NumericEditField
        KtquLabel                      matlab.ui.control.Label
        GiaTriX0_2                     matlab.ui.control.NumericEditField
        NhpgitrcnnisuyLabel            matlab.ui.control.Label
        Lamp_2                         matlab.ui.control.Lamp
        ErrorLampLabel                 matlab.ui.control.Label
        MangY_2                        matlab.ui.control.EditField
        NhpccgitryEditFieldLabel       matlab.ui.control.Label
        MangX_2                        matlab.ui.control.EditField
        NhpccgitrxEditFieldLabel       matlab.ui.control.Label
        PhuongPhapTinh_2               matlab.ui.container.ButtonGroup
        LarangeButton                  matlab.ui.control.RadioButton
        NewtonButton                   matlab.ui.control.RadioButton
        UIAxes_2                       matlab.ui.control.UIAxes
        HoiQuyTab                      matlab.ui.container.Tab
        Lamp_3                         matlab.ui.control.Lamp
        ErrorLampLabel_2               matlab.ui.control.Label
        KetQua_3                       matlab.ui.control.NumericEditField
        KtqudonLabel                   matlab.ui.control.Label
        HoiQuyButton                   matlab.ui.control.Button
        KetQuaA1                       matlab.ui.control.NumericEditField
        KtquA0Label_2                  matlab.ui.control.Label
        KetQuaA0                       matlab.ui.control.NumericEditField
        KtquA0Label                    matlab.ui.control.Label
        PhuongPhapTinh_3               matlab.ui.control.DropDown
        ChnphngphpDropDownLabel        matlab.ui.control.Label
        MangY_3                        matlab.ui.control.EditField
        NhpmngyLabel                   matlab.ui.control.Label
        MangX_3                        matlab.ui.control.EditField
        NhpmngxEditFieldLabel          matlab.ui.control.Label
        GiaTriX0_3                     matlab.ui.control.NumericEditField
        NhpgitrxLabel                  matlab.ui.control.Label
        UIAxes_3                       matlab.ui.control.UIAxes
        DaoHamTab                      matlab.ui.container.Tab
        KetQuaChinhXac_4               matlab.ui.control.NumericEditField
        KtquchnhxcLabel                matlab.ui.control.Label
        GiaTriX0_4                     matlab.ui.control.NumericEditField
        NhpgitrxLabel_2                matlab.ui.control.Label
        SaiSoLabel                     matlab.ui.control.Label
        SaiSoButton                    matlab.ui.control.StateButton
        BuocNhayH                      matlab.ui.control.NumericEditField
        BcnhyhLabel                    matlab.ui.control.Label
        Lamp_4                         matlab.ui.control.Lamp
        ErrorLampLabel_4               matlab.ui.control.Label
        KetQua_4                       matlab.ui.control.NumericEditField
        KtquLabel_3                    matlab.ui.control.Label
        PhuongPhapTinh_4               matlab.ui.control.DropDown
        ChnphngphpDropDown_2Label_2    matlab.ui.control.Label
        NhapPhuongTrinh_4              matlab.ui.control.EditField
        NhpphngtrnhEditFieldLabel_3    matlab.ui.control.Label
        mangY_4                        matlab.ui.control.EditField
        NhpccgitryEditFieldLabel_3     matlab.ui.control.Label
        mangX_4                        matlab.ui.control.EditField
        NhpccgitrxEditFieldLabel_3     matlab.ui.control.Label
        PhuongPhapNhapSwitch_4         matlab.ui.control.Switch
        ChnphngthcnhpSwitchLabel_2     matlab.ui.control.Label
        DaoHamButton                   matlab.ui.control.Button
        TichPhanTab                    matlab.ui.container.Tab
        KetQuaChinhXac_5               matlab.ui.control.NumericEditField
        KtquchnhxcLabel_2              matlab.ui.control.Label
        Lamp_5                         matlab.ui.control.Lamp
        ErrorLampLabel_3               matlab.ui.control.Label
        TichPhanButton                 matlab.ui.control.Button
        KetQua_5                       matlab.ui.control.NumericEditField
        KtquLabel_2                    matlab.ui.control.Label
        NhapNEditField                 matlab.ui.control.NumericEditField
        NhpNEditFieldLabel             matlab.ui.control.Label
        a_5                            matlab.ui.control.NumericEditField
        NhpcntnhtchphnLabel            matlab.ui.control.Label
        SymLabel_5_2                   matlab.ui.control.Label
        SymLabel_5_3                   matlab.ui.control.Label
        SymLabel_5_1                   matlab.ui.control.Label
        b_5                            matlab.ui.control.NumericEditField
        PhuongPhapTinh_5               matlab.ui.control.DropDown
        ChnphngphpDropDown_2Label      matlab.ui.control.Label
        NhapPhuongTrinh_5              matlab.ui.control.EditField
        NhpphngtrnhEditFieldLabel_2    matlab.ui.control.Label
        mangY_5                        matlab.ui.control.EditField
        NhpccgitryEditFieldLabel_2     matlab.ui.control.Label
        mangX_5                        matlab.ui.control.EditField
        NhpccgitrxEditFieldLabel_2     matlab.ui.control.Label
        PhuongPhapNhapSwitch_5         matlab.ui.control.Switch
        ChnphngthcnhpSwitchLabel       matlab.ui.control.Label
        GioiThieuTab                   matlab.ui.container.Tab
        ThanksforwatchingLabel         matlab.ui.control.Label
        NguynNhtHuyThnhvinCodertesterLabel  matlab.ui.control.Label
        LQuangHuyThnhvinTesterexplainerLabel  matlab.ui.control.Label
        NguynVnHiuThkCodertesterLabel  matlab.ui.control.Label
        HCngHiuNhmtrngLabel            matlab.ui.control.Label
        GiithiuthnhvinLabel            matlab.ui.control.Label
        NhmNOHOPELabel                 matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            warning('off', 'MATLAB:fplot:NotVectorized');
        end

        % Callback function: NghiemButton, PhuongPhapTinh_1
        function TinhButtonPushed(app, event)
            try
            app.Lamp_1.Color= 'g';
            f = app.NhapPhuongTrinh_1.Value;
            f = str2func(['@(x)',f]);
            a = app.a_1.Value;
            b = app.b_1.Value;
            fplot(app.UIAxes_1, f, [a, b]);
            saiso = app.SaiSo_1.Value;
            option1 = app.PhuongPhapTinh_1.Value;
            switch option1
                case "Chia đôi"
                    [nghiem, solanlap] = chiadoi(f,a,b,saiso);
                case "Lặp"
                    [nghiem, solanlap] = lap(f,a,b,saiso);
                case "Newton"
                    [nghiem, solanlap] = tieptuyen(f,a,b,saiso);
                    if (solanlap==0)
                        app.Lamp_1.Color= 'r';
                    end
                case "Dây cung"
                    [nghiem, solanlap] = daycung(f,a,b,saiso);
            end
            app.KetQua_1.Value = nghiem;
            app.SoLanLap.Value = solanlap;
            catch app.Lamp_1.Color= 'r';
            end
        end

        % Callback function: NoiSuyButton, PhuongPhapTinh_2
        function TinhNoiSuyPushed(app, event)
            try app.Lamp_2.Color = 'g';
            xa = str2num(app.MangX_2.Value);
            ya = str2num(app.MangY_2.Value);
            x = app.GiaTriX0_2.Value;
            if (app.NewtonButton.Value == 1)
                result = NewtonInterpolation_ns(xa, ya, x);
                f = NewtonInterpolation_dt_ns(xa, ya);         
            elseif (app.LarangeButton.Value == 1)
                result = Lagrange_ns(xa, ya, x);
                f = Lagrange_dt_ns(xa, ya);
            end
            app.KetQua_2.Value = result;
            app.DaThucNoiSuy.Value = f;
            f = str2func(['@(x)',f]);
            fplot(app.UIAxes_2, f, [xa(1), xa(end)]);
            catch app.Lamp_2.Color = 'r';
            end
        end

        % Callback function: HoiQuyButton, PhuongPhapTinh_3
        function TnhHoiQuyButtonPushed(app, event)
            try app.Lamp_3.Color = 'g';
            option3 = app.PhuongPhapTinh_3.Value;
            mangX = str2num(app.MangX_3.Value);
            mangY = str2num(app.MangY_3.Value);
            giaTriX = app.GiaTriX0_3.Value;
            if (option3 =="Tuyến tính")
                [a1, a0] = TinhHoiQuy(mangX, mangY);
                f = @(x) a1*x+a0;
            elseif (option3=="Hàm y = ae^(bx)")
                [a1, a0] = TinhHoiQuy(mangX, log(mangY));
                a0 = exp(a0);
                f = @(x) a0*(exp(a1*x));
            else
                [a1, a0] = TinhHoiQuy(log10(mangX), log10(mangY));
                a0 = 10^a0;
                f = @(x) a0*x^a1;
            end
            ketQuaDuDoan = f(giaTriX);
            plot(app.UIAxes_3, mangX, mangY, 'bo');
            hold(app.UIAxes_3, 'on');
            fplot(app.UIAxes_3, f, [mangX(1), mangX(end)], 'r');
            hold(app.UIAxes_3, 'off');
            app.KetQuaA0.Value = a0;
            app.KetQuaA1.Value = a1;
            app.KetQua_3.Value = ketQuaDuDoan;
            catch app.Lamp_3.Color = 'r';
            end
        end

        % Callback function: PhuongPhapTinh_5, TichPhanButton
        function TnhhTichPhanButtonPushed(app, event)
            try app.Lamp_5.Color = 'g';
            option5 = app.PhuongPhapTinh_5.Value;
            a = app.a_5.Value;
            b = app.b_5.Value;
            xa = str2num(app.mangX_5.Value);
            ya = str2num(app.mangY_5.Value);
            N = app.NhapNEditField.Value;
            pp = app.PhuongPhapNhapSwitch_5.Value;
            if pp == "Hàm số"
                f = app.NhapPhuongTrinh_5.Value;
                f = str2func(['@(x)',f]);
                Q = integral(f,a,b);
                app.KetQuaChinhXac_5.Value = Q;
                delta = 1;
            else
                f = ya;
                a = find(xa==a);
                b = find(xa==b);
                N = b-a;
                delta = xa(2)-xa(1);
            end
            if option5 == "Hình thang"
                y = tichphanhinhthang(f,a,b,N,delta);
            elseif option5 == "Simpson 1/3"
                y = tichphanSimpson1_3(f,a,b,N,delta);
            else
                y = tichphanSimpson3_8(f,a,b,N,delta);
            end
            app.KetQua_5.Value = y;
            catch app.Lamp_5.Color = 'r';
            end
        end

        % Value changed function: PhuongPhapNhapSwitch_5
        function PhuongPhapNhapSwitch_5ValueChanged(app, event)
            pp = app.PhuongPhapNhapSwitch_5.Value;
            if pp == "Hàm số"
                app.NhapPhuongTrinh_5.Enable = 'on';
                app.mangX_5.Enable = 'off';
                app.mangY_5.Enable = 'off';
                app.NhapNEditField.Enable = 'on';
            else
                app.NhapPhuongTrinh_5.Enable = 'off';
                app.mangX_5.Enable = 'on';
                app.mangY_5.Enable = 'on';
                app.NhapNEditField.Enable = 'off';
            end
        end

        % Button pushed function: DaoHamButton
        function TinhDaoHamButtonPushed(app, event)
            try app.Lamp_4.Color = 'g';
                h = app.BuocNhayH.Value;
                x0 = app.GiaTriX0_4.Value;
                xa = str2num(app.mangX_4.Value);
                ya = str2num(app.mangY_4.Value);
                option4 = app.PhuongPhapTinh_4.Value;
                ss = app.SaiSoButton.Text;
                pp = app.PhuongPhapNhapSwitch_4.Value;
            if pp == "Hàm số"
                f = app.NhapPhuongTrinh_4.Value;
                f = str2func(['@(x)',f]);
                syms x; % Khai báo biến ký hiệu
                df = diff(f(x), x); % Tính đạo hàm của hàm f theo biến x
                df_value = subs(df, x, x0); % Thay x vào để tính giá trị đạo hàm tại x0
                app.KetQuaChinhXac_4.Value = double(df_value);
                step = 1;
            else
                f = ya;
                x0 = find(xa==x0);
                h = 1;
                step = xa(2) - xa(1);
            end
            if (option4 == "Tiến")
                kq = DaoHamTien(f, x0, h, ss,step);
            elseif (option4 == "Lùi")
                kq = DaoHamLui(f, x0, h, ss,step);
            else
                kq = DaoHamTrungTam(f, x0, h, ss,step);
            end
            app.KetQua_4.Value = kq;
            catch app.Lamp_4.Color = 'r';
            end
        end

        % Value changed function: PhuongPhapNhapSwitch_4
        function PhuongPhapNhapSwitch_4ValueChanged(app, event)
            pp = app.PhuongPhapNhapSwitch_4.Value;
            if pp == "Hàm số"
                app.NhapPhuongTrinh_4.Enable = 'on';
                app.mangX_4.Enable = 'off';
                app.mangY_4.Enable = 'off';
                app.BuocNhayH.Enable = 'on';
            else
                app.NhapPhuongTrinh_4.Enable = 'off';
                app.mangX_4.Enable = 'on';
                app.mangY_4.Enable = 'on';
                app.BuocNhayH.Enable = 'off';
            end
        end

        % Callback function: PhuongPhapTinh_4, SaiSoButton
        function SaiSoButtonValueChanged(app, event)
            value = app.SaiSoButton.Value;
            option4 = app.PhuongPhapTinh_4.Value;
            if option4 == "Trung tâm"
                if value == 1
                    app.SaiSoButton.Text = 'O(h^4)';
                else
                    app.SaiSoButton.Text = 'O(h^2)';
                end
            else
                if value == 1
                    app.SaiSoButton.Text = 'O(h^2)';
                else
                    app.SaiSoButton.Text = 'O(h)';
                end
            end
            TinhDaoHamButtonPushed(app, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NghiemTab = uitab(app.TabGroup);
            app.NghiemTab.Title = 'Nghiệm';
            app.UIAxes_1 = uiaxes(app.NghiemTab);
            title(app.UIAxes_1, 'Đồ thị hàm số')
            xlabel(app.UIAxes_1, 'x')
            ylabel(app.UIAxes_1, 'y')
            zlabel(app.UIAxes_1, 'Z')
            app.b_1 = uieditfield(app.NghiemTab, 'numeric');
            app.b_1.Value = 1;
            app.SymLabel_1_1 = uilabel(app.NghiemTab);
            app.SymLabel_1_1.Text = '(';
            app.SymLabel_1_3 = uilabel(app.NghiemTab);
            app.SymLabel_1_3.Text = ')';
            app.SymLabel_1_2 = uilabel(app.NghiemTab);
            app.SymLabel_1_2.Text = ':';
            app.Lamp_1 = uilamp(app.NghiemTab);
            app.NhpphngtrnhEditFieldLabel = uilabel(app.NghiemTab);
            app.NhpphngtrnhEditFieldLabel.Text = 'Nhập phương trình';
            app.NhapPhuongTrinh_1 = uieditfield(app.NghiemTab, 'text');
            app.NhapPhuongTrinh_1.Value = '3*x^3 - 8*x^2 - 20*x + 16';
            app.KhongphnlinghimEditFieldLabel = uilabel(app.NghiemTab);
            app.KhongphnlinghimEditFieldLabel.Text = 'Khoảng phân li nghiệm';
            app.a_1 = uieditfield(app.NghiemTab, 'numeric');
            app.PhngphptnhDropDownLabel = uilabel(app.NghiemTab);
            app.PhngphptnhDropDownLabel.Text = 'Phương pháp tính';
            app.PhuongPhapTinh_1 = uidropdown(app.NghiemTab);
            app.PhuongPhapTinh_1.Items = {'Chia đôi', 'Lặp', 'Newton', 'Dây cung'};
            app.PhuongPhapTinh_1.ClickedFcn = createCallbackFcn(app, @TinhButtonPushed, true);
            app.PhuongPhapTinh_1.Value = 'Chia đôi';
            app.SaischophpEditFieldLabel = uilabel(app.NghiemTab);
            app.SaischophpEditFieldLabel.Text = 'Sai số cho phép';
            app.SaiSo_1 = uieditfield(app.NghiemTab, 'numeric');
            app.SaiSo_1.Value = 0.005;
            app.KtquEditFieldLabel = uilabel(app.NghiemTab);
            app.KtquEditFieldLabel.Text = 'Kết quả';
            app.KetQua_1 = uieditfield(app.NghiemTab, 'numeric');
            app.KetQua_1.Editable = 'off';
            app.SlnlpEditFieldLabel = uilabel(app.NghiemTab);
            app.SlnlpEditFieldLabel.Text = 'Số lần lặp';
            app.SoLanLap = uieditfield(app.NghiemTab, 'numeric');
            app.SoLanLap.Editable = 'off';
            app.NghiemButton = uibutton(app.NghiemTab, 'push');
            app.NghiemButton.ButtonPushedFcn = createCallbackFcn(app, @TinhButtonPushed, true);
            app.NghiemButton.Text = 'Caculator';
            app.NoiSuyTab = uitab(app.TabGroup);
            app.NoiSuyTab.Title = 'Nội Suy';
            app.UIAxes_2 = uiaxes(app.NoiSuyTab);
            title(app.UIAxes_2, 'Đồ thị hàm số nội suy')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.PhuongPhapTinh_2 = uibuttongroup(app.NoiSuyTab);
            app.PhuongPhapTinh_2.SelectionChangedFcn = createCallbackFcn(app, @TinhNoiSuyPushed, true);
            app.PhuongPhapTinh_2.Title = 'Phương pháp';
            app.NewtonButton = uiradiobutton(app.PhuongPhapTinh_2);
            app.NewtonButton.Text = 'Newton';
            app.NewtonButton.Value = true;
            app.LarangeButton = uiradiobutton(app.PhuongPhapTinh_2);
            app.LarangeButton.Text = 'Larange';
            app.NhpccgitrxEditFieldLabel = uilabel(app.NoiSuyTab);
            app.NhpccgitrxEditFieldLabel.Text = 'Nhập các giá trị x';
            app.MangX_2 = uieditfield(app.NoiSuyTab, 'text');
            app.MangX_2.Value = '0.1 0.2 0.3 0.4';
            app.NhpccgitryEditFieldLabel = uilabel(app.NoiSuyTab);
            app.NhpccgitryEditFieldLabel.Text = 'Nhập các giá trị y';
            app.MangY_2 = uieditfield(app.NoiSuyTab, 'text');
            app.MangY_2.Value = '0.09983 0.19867 0.29552 0.38942';
            app.ErrorLampLabel = uilabel(app.NoiSuyTab);
            app.ErrorLampLabel.Text = 'Error';
            app.Lamp_2 = uilamp(app.NoiSuyTab);
            app.NhpgitrcnnisuyLabel = uilabel(app.NoiSuyTab);
            app.NhpgitrcnnisuyLabel.Text = 'Nhập giá trị cần nội suy';
            app.GiaTriX0_2 = uieditfield(app.NoiSuyTab, 'numeric');
            app.GiaTriX0_2.Value = 0.14;
            app.KtquLabel = uilabel(app.NoiSuyTab);
            app.KtquLabel.Text = 'Kết quả nội suy';
            app.KetQua_2 = uieditfield(app.NoiSuyTab, 'numeric');
            app.KetQua_2.Editable = 'off';
            app.athcnisuyLabel = uilabel(app.NoiSuyTab);
            app.athcnisuyLabel.Text = 'Đa thức nội suy';
            app.DaThucNoiSuy = uieditfield(app.NoiSuyTab, 'text');
            app.DaThucNoiSuy.Editable = 'off';
            app.DaThucNoiSuy.Value = 'NaN';
            app.NoiSuyButton = uibutton(app.NoiSuyTab, 'push');
            app.NoiSuyButton.ButtonPushedFcn = createCallbackFcn(app, @TinhNoiSuyPushed, true);
            app.NoiSuyButton.Text = 'Caculator';
            app.HoiQuyTab = uitab(app.TabGroup);
            app.HoiQuyTab.Title = 'Hồi quy';
            app.UIAxes_3 = uiaxes(app.HoiQuyTab);
            title(app.UIAxes_3, 'Đồ thị hồi quy')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.NhpgitrxLabel = uilabel(app.HoiQuyTab);
            app.NhpgitrxLabel.Text = 'Giá trị cần dự đoán';
            app.GiaTriX0_3 = uieditfield(app.HoiQuyTab, 'numeric');
            app.GiaTriX0_3.Value = 0.5;
            app.NhpmngxEditFieldLabel = uilabel(app.HoiQuyTab);
            app.NhpmngxEditFieldLabel.Text = 'Nhập mảng x';
            app.MangX_3 = uieditfield(app.HoiQuyTab, 'text');
            app.MangX_3.Value = '1, 2, 3, 4, 5, 6, 7, 8, 9';
            app.NhpmngyLabel = uilabel(app.HoiQuyTab);
            app.NhpmngyLabel.Text = 'Nhập mảng y';
            app.MangY_3 = uieditfield(app.HoiQuyTab, 'text');
            app.MangY_3.Value = '1, 1.5, 2, 3, 4, 5, 8, 10, 13';
            app.ChnphngphpDropDownLabel = uilabel(app.HoiQuyTab);
            app.ChnphngphpDropDownLabel.Text = 'Chọn phương pháp';
            app.PhuongPhapTinh_3 = uidropdown(app.HoiQuyTab);
            app.PhuongPhapTinh_3.Items = {'Tuyến tính', 'Hàm y = ae^(bx)', 'Hàm y = ax^b'};
            app.PhuongPhapTinh_3.ClickedFcn = createCallbackFcn(app, @TnhHoiQuyButtonPushed, true);
            app.PhuongPhapTinh_3.Value = 'Tuyến tính';
            app.KtquA0Label = uilabel(app.HoiQuyTab);
            app.KtquA0Label.Text = 'Kết quả A0 (a)';
            app.KetQuaA0 = uieditfield(app.HoiQuyTab, 'numeric');
            app.KetQuaA0.Editable = 'off';
            app.KtquA0Label_2 = uilabel(app.HoiQuyTab);
            app.KtquA0Label_2.Text = 'Kết quả A1 (b)';
            app.KetQuaA1 = uieditfield(app.HoiQuyTab, 'numeric');
            app.KetQuaA1.Editable = 'off';
            app.HoiQuyButton = uibutton(app.HoiQuyTab, 'push');
            app.HoiQuyButton.ButtonPushedFcn = createCallbackFcn(app, @TnhHoiQuyButtonPushed, true);
            app.HoiQuyButton.Text = 'Caculator';
            app.KtqudonLabel = uilabel(app.HoiQuyTab);
            app.KtqudonLabel.Text = 'Kết quả dự đoán';
            app.KetQua_3 = uieditfield(app.HoiQuyTab, 'numeric');
            app.KetQua_3.Editable = 'off';
            app.ErrorLampLabel_2 = uilabel(app.HoiQuyTab);
            app.ErrorLampLabel_2.Text = 'Error';
            app.Lamp_3 = uilamp(app.HoiQuyTab);
            app.DaoHamTab = uitab(app.TabGroup);
            app.DaoHamTab.Title = 'Đạo Hàm';
            app.DaoHamButton = uibutton(app.DaoHamTab, 'push');
            app.DaoHamButton.ButtonPushedFcn = createCallbackFcn(app, @TinhDaoHamButtonPushed, true);
            app.DaoHamButton.Text = 'Caculator';
            app.ChnphngthcnhpSwitchLabel_2 = uilabel(app.DaoHamTab);
            app.ChnphngthcnhpSwitchLabel_2.Text = 'Chọn phương thức nhập';
            app.PhuongPhapNhapSwitch_4 = uiswitch(app.DaoHamTab, 'slider');
            app.PhuongPhapNhapSwitch_4.Items = {'Hàm số', 'Mảng giá trị'};
            app.PhuongPhapNhapSwitch_4.ValueChangedFcn = createCallbackFcn(app, @PhuongPhapNhapSwitch_4ValueChanged, true);
            app.PhuongPhapNhapSwitch_4.Value = 'Hàm số';
            app.NhpccgitrxEditFieldLabel_3 = uilabel(app.DaoHamTab);
            app.NhpccgitrxEditFieldLabel_3.Text = 'Nhập các giá trị x';
            app.mangX_4 = uieditfield(app.DaoHamTab, 'text');
            app.mangX_4.Enable = 'off';
            app.mangX_4.Value = '0.1 0.3 0.5 0.7 0.9';
            app.NhpccgitryEditFieldLabel_3 = uilabel(app.DaoHamTab);
            app.NhpccgitryEditFieldLabel_3.Text = 'Nhập các giá trị y';
            app.mangY_4 = uieditfield(app.DaoHamTab, 'text');
            app.mangY_4.Enable = 'off';
            app.mangY_4.Value = '0.1002 0.3047 0.5236 0.7754 1.1198';
            app.NhpphngtrnhEditFieldLabel_3 = uilabel(app.DaoHamTab);
            app.NhpphngtrnhEditFieldLabel_3.Text = 'Nhập phương trình';
            app.NhapPhuongTrinh_4 = uieditfield(app.DaoHamTab, 'text');
            app.NhapPhuongTrinh_4.Value = '-0.1*x^4-0.15*x^3-0.5*x^2-0.25*x+1.2';
            app.ChnphngphpDropDown_2Label_2 = uilabel(app.DaoHamTab);
            app.ChnphngphpDropDown_2Label_2.Text = 'Chọn phương pháp xấp xỉ';
            app.PhuongPhapTinh_4 = uidropdown(app.DaoHamTab);
            app.PhuongPhapTinh_4.Items = {'Tiến', 'Lùi', 'Trung tâm'};
            app.PhuongPhapTinh_4.ClickedFcn = createCallbackFcn(app, @SaiSoButtonValueChanged, true);
            app.PhuongPhapTinh_4.Value = 'Tiến';
            app.KtquLabel_3 = uilabel(app.DaoHamTab);
            app.KtquLabel_3.Text = 'Kết quả';
            app.KetQua_4 = uieditfield(app.DaoHamTab, 'numeric');
            app.KetQua_4.Editable = 'off';
            app.ErrorLampLabel_4 = uilabel(app.DaoHamTab);
            app.ErrorLampLabel_4.Text = 'Error';
            app.Lamp_4 = uilamp(app.DaoHamTab);
            app.BcnhyhLabel = uilabel(app.DaoHamTab);
            app.BcnhyhLabel.Text = 'Bước nhảy h';
            app.BuocNhayH = uieditfield(app.DaoHamTab, 'numeric');
            app.BuocNhayH.Value = 0.25;
            app.SaiSoButton = uibutton(app.DaoHamTab, 'state');
            app.SaiSoButton.ValueChangedFcn = createCallbackFcn(app, @SaiSoButtonValueChanged, true);
            app.SaiSoButton.Text = 'O(h)';
            app.SaiSoLabel = uilabel(app.DaoHamTab);
            app.SaiSoLabel.Text = 'Giá trị sai số đang áp dụng';
            app.NhpgitrxLabel_2 = uilabel(app.DaoHamTab);
            app.NhpgitrxLabel_2.Text = 'Giá trị tính đạo hàm';
            app.GiaTriX0_4 = uieditfield(app.DaoHamTab, 'numeric');
            app.GiaTriX0_4.Value = 0.5;
            app.KtquchnhxcLabel = uilabel(app.DaoHamTab);
            app.KtquchnhxcLabel.Text = {'Kết quả chính xác'; '(của hàm số)'};
            app.KetQuaChinhXac_4 = uieditfield(app.DaoHamTab, 'numeric');
            app.TichPhanTab = uitab(app.TabGroup);
            app.TichPhanTab.Title = 'Tích Phân';
            app.ChnphngthcnhpSwitchLabel = uilabel(app.TichPhanTab);
            app.ChnphngthcnhpSwitchLabel.Text = 'Chọn phương thức nhập';
            app.PhuongPhapNhapSwitch_5 = uiswitch(app.TichPhanTab, 'slider');
            app.PhuongPhapNhapSwitch_5.Items = {'Hàm số', 'Mảng giá trị'};
            app.PhuongPhapNhapSwitch_5.ValueChangedFcn = createCallbackFcn(app, @PhuongPhapNhapSwitch_5ValueChanged, true);
            app.PhuongPhapNhapSwitch_5.Value = 'Hàm số';
            app.NhpccgitrxEditFieldLabel_2 = uilabel(app.TichPhanTab);
            app.NhpccgitrxEditFieldLabel_2.Text = 'Nhập các giá trị x';
            app.mangX_5 = uieditfield(app.TichPhanTab, 'text');
            app.mangX_5.Enable = 'off';
            app.mangX_5.Value = '0 0.2 0.4 0.6 0.8 1';
            app.NhpccgitryEditFieldLabel_2 = uilabel(app.TichPhanTab);
            app.NhpccgitryEditFieldLabel_2.Text = 'Nhập các giá trị y';
            app.mangY_5 = uieditfield(app.TichPhanTab, 'text');
            app.mangY_5.Enable = 'off';
            app.mangY_5.Value = '0 0.1976 0.3933 0.6171 0.9247 1.3818';
            app.NhpphngtrnhEditFieldLabel_2 = uilabel(app.TichPhanTab);
            app.NhpphngtrnhEditFieldLabel_2.Text = 'Nhập phương trình';
            app.NhapPhuongTrinh_5 = uieditfield(app.TichPhanTab, 'text');
            app.NhapPhuongTrinh_5.Value = 'x.^3.*sin(x)+x.*cos(x)';
            app.ChnphngphpDropDown_2Label = uilabel(app.TichPhanTab);
            app.ChnphngphpDropDown_2Label.Text = 'Chọn phương pháp';
            app.PhuongPhapTinh_5 = uidropdown(app.TichPhanTab);
            app.PhuongPhapTinh_5.Items = {'Hình thang', 'Simpson 1/3', 'Simpson 3/8'};
            app.PhuongPhapTinh_5.ClickedFcn = createCallbackFcn(app, @TnhhTichPhanButtonPushed, true);
            app.PhuongPhapTinh_5.Value = 'Hình thang';
            app.b_5 = uieditfield(app.TichPhanTab, 'numeric');
            app.b_5.Value = 1;
            app.SymLabel_5_1 = uilabel(app.TichPhanTab);
            app.SymLabel_5_1.Text = '(';
            app.SymLabel_5_3 = uilabel(app.TichPhanTab);
            app.SymLabel_5_3.Text = ')';
            app.SymLabel_5_2 = uilabel(app.TichPhanTab);
            app.SymLabel_5_2.Text = ':';
            app.NhpcntnhtchphnLabel = uilabel(app.TichPhanTab);
            app.NhpcntnhtchphnLabel.Text = 'Nhập cận tính tích phân';
            app.a_5 = uieditfield(app.TichPhanTab, 'numeric');
            app.NhpNEditFieldLabel = uilabel(app.TichPhanTab);
            app.NhpNEditFieldLabel.Text = 'Nhập N:';
            app.NhapNEditField = uieditfield(app.TichPhanTab, 'numeric');
            app.NhapNEditField.Value = 10;
            app.KtquLabel_2 = uilabel(app.TichPhanTab);
            app.KtquLabel_2.Text = 'Kết quả';
            app.KetQua_5 = uieditfield(app.TichPhanTab, 'numeric');
            app.KetQua_5.Editable = 'off';
            app.TichPhanButton = uibutton(app.TichPhanTab, 'push');
            app.TichPhanButton.ButtonPushedFcn = createCallbackFcn(app, @TnhhTichPhanButtonPushed, true);
            app.TichPhanButton.Text = 'Caculator';
            app.ErrorLampLabel_3 = uilabel(app.TichPhanTab);
            app.ErrorLampLabel_3.Text = 'Error';
            app.Lamp_5 = uilamp(app.TichPhanTab);
            app.KtquchnhxcLabel_2 = uilabel(app.TichPhanTab);
            app.KtquchnhxcLabel_2.Text = {'Kết quả chính xác'; '(của hàm số)'};
            app.KetQuaChinhXac_5 = uieditfield(app.TichPhanTab, 'numeric');
            app.GioiThieuTab = uitab(app.TabGroup);
            app.GioiThieuTab.Title = 'Giới Thiệu Nhóm';
            app.NhmNOHOPELabel = uilabel(app.GioiThieuTab);
            app.NhmNOHOPELabel.Text = 'Nhóm NO HOPE';
            app.GiithiuthnhvinLabel = uilabel(app.GioiThieuTab);
            app.GiithiuthnhvinLabel.Text = 'Giới thiệu thành viên:';
            app.HCngHiuNhmtrngLabel = uilabel(app.GioiThieuTab);
            app.HCngHiuNhmtrngLabel.Text = '21200289 - Hồ Công Hiếu - Nhóm trưởng';
            app.NguynVnHiuThkCodertesterLabel = uilabel(app.GioiThieuTab);
            app.NguynVnHiuThkCodertesterLabel.Text = '21200290 - Nguyễn Văn Hiếu - Thư ký (Coder, tester)';
            app.LQuangHuyThnhvinTesterexplainerLabel = uilabel(app.GioiThieuTab);
            app.LQuangHuyThnhvinTesterexplainerLabel.Text = '21200293 - Lê Quang Huy - Thành viên (Tester, explainer)';
            app.NguynNhtHuyThnhvinCodertesterLabel = uilabel(app.GioiThieuTab);
            app.NguynNhtHuyThnhvinCodertesterLabel.Text = '21200294 - Nguyễn Nhất Huy - Thành viên (Coder, tester)';
            app.ThanksforwatchingLabel = uilabel(app.GioiThieuTab);
            app.ThanksforwatchingLabel.Text = '*** Thanks for watching! ***';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DO_AN

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end